classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        GradientTab                 matlab.ui.container.Tab
        GradientSigmaField          matlab.ui.control.NumericEditField
        GradientSigmaLabel          matlab.ui.control.Label
        GradientT2Field             matlab.ui.control.NumericEditField
        GradientT2Label             matlab.ui.control.Label
        GradientTLabel              matlab.ui.control.Label
        GradientComputeButton       matlab.ui.control.Button
        GradientTField              matlab.ui.control.NumericEditField
        SegmentationResultPanel     matlab.ui.container.Panel
        GridLayout3_5               matlab.ui.container.GridLayout
        GradientSegmentationImage   matlab.ui.control.Image
        GradientOperatorDropDown    matlab.ui.control.DropDown
        OperatorDropDownLabel       matlab.ui.control.Label
        EdgeDetectionResultPanel    matlab.ui.container.Panel
        GridLayout3_4               matlab.ui.container.GridLayout
        GradientEdgeDetectionImage  matlab.ui.control.Image
        OriginalImagePanel          matlab.ui.container.Panel
        GridLayout4_4               matlab.ui.container.GridLayout
        GradientOriginalImage       matlab.ui.control.Image
        GradientBrowseButton        matlab.ui.control.Button
        LaplacianTab                matlab.ui.container.Tab
    end

    
    properties (Access = private)
        img % Description
        tab = 'gradient' % Description
    end
    
    methods (Access = private)

        function generateTabContent(app)
            if isempty(app.img)
                return
            end

            switch app.tab
                case 'gradient'
                    app.generateGradientTab();
                case 'laplacian'
                    app.generateLaplacianTab();
            end
        end

        function generateGradientTab(app)
            if isempty(app.img)
                return
            end
            
            % Edge detection
            op = app.GradientOperatorDropDown.Value;
            T = app.GradientTField.Value;
            T2 = app.GradientT2Field.Value;
            sigma = app.GradientSigmaField.Value;
            [edges, edgeImage] = grdGetEdgeImage(app.img, op, T, T2, sigma); 

            app.GradientEdgeDetectionImage.ImageSource = edgeImage;

            % Image segmentation
            segmentedImage = getSegmentedImage(app.img, edges);
            app.GradientSegmentationImage.ImageSource = segmentedImage;
        end

        function generateLaplacianTab(app)
            if isempty(app.img)
                return
            end
            
            % kindly add laplacian here, queen mellisa :3
        end
        
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: GradientTab
        function GradientTabButtonDown(app, event)
            app.tab = 'gradient';
            app.img = app.GradientOriginalImage.ImageSource;
            app.generateTabContent();
        end

        % Button pushed function: GradientBrowseButton
        function GradientBrowseButtonPushed(app, event)
            extensions = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [filename, path] = uigetfile(extensions, 'Open file');

            if (isequal(filename, 0))
                return
            end

            image = imread(fullfile(path, filename));
            app.GradientOriginalImage.ImageSource = image;
            
            app.img = image;
            app.generateTabContent();
        end

        % Value changed function: GradientOperatorDropDown
        function GradientDropDownOnChanged(app, event)
            value = app.GradientOperatorDropDown.Value;
            
            if (strcmp(value, "Canny"))
                app.GradientT2Label.Visible = "on";
                app.GradientT2Field.Visible = "on";

                app.GradientSigmaLabel.Visible = "on";
                app.GradientSigmaField.Visible = "on";
            else
                app.GradientT2Label.Visible = "off";
                app.GradientT2Field.Visible = "off";

                app.GradientSigmaLabel.Visible = "off";
                app.GradientSigmaField.Visible = "off";
            end
        end

        % Button down function: LaplacianTab
        function LaplacianTabButtonDown(app, event)
            app.tab = 'laplacian';
            %app.img = app.GradientOriginalImage.ImageSource;
            app.generateTabContent();
        end

        % Button pushed function: GradientComputeButton
        function ComputeButtonPushed(app, event)
            app.generateTabContent();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GradientTab = uitab(app.TabGroup);
            app.GradientTab.Title = 'Gradient';
            app.GradientTab.ButtonDownFcn = createCallbackFcn(app, @GradientTabButtonDown, true);
            app.OriginalImagePanel = uipanel(app.GradientTab);
            app.OriginalImagePanel.Title = 'Original Image';
            app.GridLayout4_4 = uigridlayout(app.OriginalImagePanel);
            app.GradientBrowseButton = uibutton(app.GridLayout4_4, 'push');
            app.GradientBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @GradientBrowseButtonPushed, true);
            app.GradientBrowseButton.Text = 'Browse';
            app.GradientOriginalImage = uiimage(app.GridLayout4_4);
            app.EdgeDetectionResultPanel = uipanel(app.GradientTab);
            app.EdgeDetectionResultPanel.Title = 'Edge Detection Result';
            app.GridLayout3_4 = uigridlayout(app.EdgeDetectionResultPanel);
            app.GradientEdgeDetectionImage = uiimage(app.GridLayout3_4);
            app.OperatorDropDownLabel = uilabel(app.GradientTab);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.GradientOperatorDropDown = uidropdown(app.GradientTab);
            app.GradientOperatorDropDown.Items = {'Sobel', 'Roberts', 'Prewitt', 'Canny'};
            app.GradientOperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @GradientDropDownOnChanged, true);
            app.GradientOperatorDropDown.Value = 'Sobel';
            app.SegmentationResultPanel = uipanel(app.GradientTab);
            app.SegmentationResultPanel.Title = 'Segmentation Result';
            app.GridLayout3_5 = uigridlayout(app.SegmentationResultPanel);
            app.GradientSegmentationImage = uiimage(app.GridLayout3_5);
            app.GradientTField = uieditfield(app.GradientTab, 'numeric');
            app.GradientTField.Limits = [0 255];
            app.GradientTField.Value = 30;
            app.GradientComputeButton = uibutton(app.GradientTab, 'push');
            app.GradientComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.GradientComputeButton.Text = 'Compute';
            app.GradientTLabel = uilabel(app.GradientTab);
            app.GradientTLabel.Text = 'T';
            app.GradientT2Label = uilabel(app.GradientTab);
            app.GradientT2Label.Visible = 'off';
            app.GradientT2Label.Text = 'T2';
            app.GradientT2Field = uieditfield(app.GradientTab, 'numeric');
            app.GradientT2Field.Limits = [0 255];
            app.GradientT2Field.Visible = 'off';
            app.GradientT2Field.Value = 80;
            app.GradientSigmaLabel = uilabel(app.GradientTab);
            app.GradientSigmaLabel.Visible = 'off';
            app.GradientSigmaLabel.Text = 'Sigma';
            app.GradientSigmaField = uieditfield(app.GradientTab, 'numeric');
            app.GradientSigmaField.Visible = 'off';
            app.GradientSigmaField.Value = 1.5;
            app.LaplacianTab = uitab(app.TabGroup);
            app.LaplacianTab.Title = 'Laplacian';
            app.LaplacianTab.ButtonDownFcn = createCallbackFcn(app, @LaplacianTabButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end